classdef CeuImgProcApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadimageMenu                   matlab.ui.container.Menu
        UpdateimagelistMenu             matlab.ui.container.Menu
        QuitMenu                        matlab.ui.container.Menu
        LecturesMenu                    matlab.ui.container.Menu
        Lecture02DigitalimagesMenu      matlab.ui.container.Menu
        SeparateRGBchannelsMenu         matlab.ui.container.Menu
        MergeRGBchannelsMenu            matlab.ui.container.Menu
        Lecture03ImagehistogramsMenu    matlab.ui.container.Menu
        ComputeintensityMenu            matlab.ui.container.Menu
        PDFandCDFMenu                   matlab.ui.container.Menu
        Lecture04PointprocessMenu       matlab.ui.container.Menu
        IncreaseordecreasecontrastMenu  matlab.ui.container.Menu
        SelectimageDropDownLabel        matlab.ui.control.Label
        SelectimageDropDown             matlab.ui.control.DropDown
    end

    
    methods (Access = public)
        function updateImageList(app)
            figHandles = findobj('Type', 'figure');
            if length(figHandles)>0
                app.SelectimageDropDown.Items = {};
                for i=1:length(figHandles)
                    app.SelectimageDropDown.Items{i} = figHandles(i).Name;
                end
            end
        end
    end
    
    methods (Access = public)
        function h = getFigureHandler(app)
            h= app.getFigureHandlerByName(app.SelectimageDropDown.Value);
        end
    end
    
    methods (Access = public)
        
        function h = getFigureHandlerByName(app, selectedName)
            figHandles = findobj('Type', 'figure');
            for i=1:length(figHandles)
                if strcmp(figHandles(i).Name,selectedName)
                    h=figHandles(i);
                    return
                end
            end
            h = -1;
            return            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: LoadimageMenu
        function LoadimageMenuSelected(app, event)
            [file,path] = uigetfile('*.*','Select an Image');
            if ~isequal(file,0)
               fn=fullfile(path,file);
               [I,map] = imread(fn);
               figure('Name',file);
               imshow(I,map);
               
               app.updateImageList();
            end
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            close all
            closereq
        end

        % Menu selected function: SeparateRGBchannelsMenu
        function SeparateRGBchannelsMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL2_SplitRGBChannelsApp(app,app.getFigureHandler());
            end
        end

        % Menu selected function: UpdateimagelistMenu
        function UpdateimagelistMenuSelected(app, event)
            app.updateImageList();
        end

        % Menu selected function: MergeRGBchannelsMenu
        function MergeRGBchannelsMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL2_MergeRGBChannelsApp(app,app.getFigureHandler());
            end            
        end

        % Menu selected function: ComputeintensityMenu
        function ComputeintensityMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL3_ComputeIntensityApp(app,app.getFigureHandler());
            end                        
        end

        % Menu selected function: PDFandCDFMenu
        function PDFandCDFMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL3_ComputePdfAndCdfApp(app,app.getFigureHandler());
            end                        
        end

        % Menu selected function: IncreaseordecreasecontrastMenu
        function IncreaseordecreasecontrastMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL4_IncreaseDecreaseContrastApp(app,app.getFigureHandler());
            end                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadimageMenu = uimenu(app.FileMenu);
            app.LoadimageMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadimageMenuSelected, true);
            app.LoadimageMenu.Text = 'Load image';
            app.UpdateimagelistMenu = uimenu(app.FileMenu);
            app.UpdateimagelistMenu.MenuSelectedFcn = createCallbackFcn(app, @UpdateimagelistMenuSelected, true);
            app.UpdateimagelistMenu.Text = 'Update image list';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.LecturesMenu = uimenu(app.UIFigure);
            app.LecturesMenu.Text = 'Lectures';
            app.Lecture02DigitalimagesMenu = uimenu(app.LecturesMenu);
            app.Lecture02DigitalimagesMenu.Text = 'Lecture 02. Digital images';
            app.SeparateRGBchannelsMenu = uimenu(app.Lecture02DigitalimagesMenu);
            app.SeparateRGBchannelsMenu.MenuSelectedFcn = createCallbackFcn(app, @SeparateRGBchannelsMenuSelected, true);
            app.SeparateRGBchannelsMenu.Text = 'Separate RGB channels';
            app.MergeRGBchannelsMenu = uimenu(app.Lecture02DigitalimagesMenu);
            app.MergeRGBchannelsMenu.MenuSelectedFcn = createCallbackFcn(app, @MergeRGBchannelsMenuSelected, true);
            app.MergeRGBchannelsMenu.Text = 'Merge RGB channels';
            app.Lecture03ImagehistogramsMenu = uimenu(app.LecturesMenu);
            app.Lecture03ImagehistogramsMenu.Text = 'Lecture 03. Image histograms';
            app.ComputeintensityMenu = uimenu(app.Lecture03ImagehistogramsMenu);
            app.ComputeintensityMenu.MenuSelectedFcn = createCallbackFcn(app, @ComputeintensityMenuSelected, true);
            app.ComputeintensityMenu.Text = 'Compute intensity';
            app.PDFandCDFMenu = uimenu(app.Lecture03ImagehistogramsMenu);
            app.PDFandCDFMenu.MenuSelectedFcn = createCallbackFcn(app, @PDFandCDFMenuSelected, true);
            app.PDFandCDFMenu.Text = 'PDF and CDF';
            app.Lecture04PointprocessMenu = uimenu(app.LecturesMenu);
            app.Lecture04PointprocessMenu.Text = 'Lecture 04. Point process';
            app.IncreaseordecreasecontrastMenu = uimenu(app.Lecture04PointprocessMenu);
            app.IncreaseordecreasecontrastMenu.MenuSelectedFcn = createCallbackFcn(app, @IncreaseordecreasecontrastMenuSelected, true);
            app.IncreaseordecreasecontrastMenu.Text = 'Increase or decrease contrast';
            app.SelectimageDropDownLabel = uilabel(app.UIFigure);
            app.SelectimageDropDownLabel.Text = 'Select image';
            app.SelectimageDropDown = uidropdown(app.UIFigure);
            app.SelectimageDropDown.Items = {};
            app.SelectimageDropDown.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuImgProcApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end